classdef app_advancedFeature < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AdvancedFeaturesUIFigure   matlab.ui.Figure
        MainGridLayout             matlab.ui.container.GridLayout
        CloseLinePanel             matlab.ui.container.Panel
        CloseLineGridLayout        matlab.ui.container.GridLayout
        CloseLineHyperlink         matlab.ui.control.Hyperlink
        CloseLineDropDown          matlab.ui.control.DropDown
        OnePixelLinePanel          matlab.ui.container.Panel
        OnePixelGridLayout         matlab.ui.container.GridLayout
        OnePixelDropDown           matlab.ui.control.DropDown
        OnePixelHyperlink          matlab.ui.control.Hyperlink
        DenseHyperlink             matlab.ui.control.Hyperlink
        DenseSegmentationCheckBox  matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        callingApp % The calling app
    end
    
    methods (Access = private)
        
        % Load close line algorithms
        % (see close-algorithms folder for more details)
        function loadCloseLineAlgorithms(app)
            closeAlgorithmsNames = dir(fullfile("api", "utils",... 
                "functions", "segmentations", "closeAlgorithms", "*.m"));

            % Needed for the standalone version
            closeAlgorithmsNames = [closeAlgorithmsNames, dir("close_*.m")];

            closeAlgorithmsNames = arrayfun(@(v) extractBetween(v.name, "close_", ".m"), closeAlgorithmsNames);
            closeAlgorithmsNames = arrayfun(@(v) fromCamelCaseToSpacedString(string(v)), closeAlgorithmsNames);
            app.CloseLineDropDown.Items = closeAlgorithmsNames;

            app.CloseLineDropDown.Value = "Geodesic Active Contour";
        end
            
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            app.AdvancedFeaturesUIFigure.Visible = "off";
            movegui(app.AdvancedFeaturesUIFigure, "center");
            app.AdvancedFeaturesUIFigure.Visible = "on";

            % Store inputs
            app.callingApp = caller;

            % Load close line algortihms
            app.loadCloseLineAlgorithms();
        end

        % Value changed function: DenseSegmentationCheckBox
        function DenseSegmentationCheckBoxValueChanged(app, event)
            value = app.DenseSegmentationCheckBox.Value;
            
            % Handle incompatibility
            if value
                app.OnePixelDropDown.Enable = "off";
            else
                app.OnePixelDropDown.Enable = "on";
            end

            app.callingApp.setDense(value);
        end

        % Callback function: DenseHyperlink
        function DenseHyperlinkClicked(app, event)
            uiconfirm(app.AdvancedFeaturesUIFigure, "True if a dense object is present and more tests are required, false otherwise." + ...
                newline + "It adds more check to the segmentation to be able to better recognize open lines and close dense lines." + ...
                newline + "Be careful, it is difficult to recognize a dense object from an open line of more than one pixel." + ...
                newline + "Since an open line of more than one pixel is, by definition, a dense object we use some statistics to " + ...
                "recognize a dense object from an open line of more than one pixel.", ...
                "Dense Segmentation", "Options", "OK", "Icon", "info");
        end

        % Callback function: OnePixelHyperlink
        function OnePixelHyperlinkClicked(app, event)
            uiconfirm(app.AdvancedFeaturesUIFigure, "Select the line you want to use for input segmentations of more than one pixel." + ...
                newline + "The options are: external, internal and middle." + newline + "Only external line is compatible " + ...
                "with `dense` option.", "One Pixel Line", "Options", "OK", "Icon", "info");
        end

        % Value changed function: OnePixelDropDown
        function OnePixelDropDownValueChanged(app, event)
            value = app.OnePixelDropDown.Value;
            
            if ~strcmp(value, app.OnePixelDropDown.Items(1))
                app.DenseSegmentationCheckBox.Enable = "off";
            else
                app.DenseSegmentationCheckBox.Enable = "on";
            end

            app.callingApp.setOnePixelLine(lower(value));
        end

        % Value changed function: CloseLineDropDown
        function CloseLineDropDownValueChanged(app, event)
            value = app.CloseLineDropDown.Value;

            algorithm = fromSpacedToCamelCaseString(value);
            algorithm = strcat("close_", algorithm);
            app.callingApp.setCloseLineAlgorithm(algorithm);
        end

        % Callback function: CloseLineHyperlink
        function CloseLineHyperlinkClicked(app, event)
            uiconfirm(app.AdvancedFeaturesUIFigure, "Select the algorithm you want to use to close the resulting " + ...
                "segmentation (if it is not already closed) after the fusion process.", ...
                "Close Line Algorithm", "Options", "OK", "Icon", "info");     
        end

        % Close request function: AdvancedFeaturesUIFigure
        function AdvancedFeaturesUIFigureCloseRequest(app, event)
            app.callingApp.enableAdvancedFeatures();
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AdvancedFeaturesUIFigure = uifigure('Visible', 'off');
            app.AdvancedFeaturesUIFigure.Name = 'Advanced Features';
            app.AdvancedFeaturesUIFigure.CloseRequestFcn = createCallbackFcn(app, @AdvancedFeaturesUIFigureCloseRequest, true);
            app.MainGridLayout = uigridlayout(app.AdvancedFeaturesUIFigure);
            app.DenseSegmentationCheckBox = uicheckbox(app.MainGridLayout);
            app.DenseSegmentationCheckBox.ValueChangedFcn = createCallbackFcn(app, @DenseSegmentationCheckBoxValueChanged, true);
            app.DenseSegmentationCheckBox.Text = 'Dense Segmentation';
            app.DenseHyperlink = uihyperlink(app.MainGridLayout);
            app.DenseHyperlink.HyperlinkClickedFcn = createCallbackFcn(app, @DenseHyperlinkClicked, true);
            app.DenseHyperlink.Text = 'Help ...';
            app.OnePixelLinePanel = uipanel(app.MainGridLayout);
            app.OnePixelLinePanel.Title = 'One Pixel Line';
            app.OnePixelGridLayout = uigridlayout(app.OnePixelLinePanel);
            app.OnePixelHyperlink = uihyperlink(app.OnePixelGridLayout);
            app.OnePixelHyperlink.HyperlinkClickedFcn = createCallbackFcn(app, @OnePixelHyperlinkClicked, true);
            app.OnePixelHyperlink.Text = 'Help...';
            app.OnePixelDropDown = uidropdown(app.OnePixelGridLayout);
            app.OnePixelDropDown.Items = {'External', 'Middle ', 'Internal'};
            app.OnePixelDropDown.ValueChangedFcn = createCallbackFcn(app, @OnePixelDropDownValueChanged, true);
            app.OnePixelDropDown.Value = 'External';
            app.CloseLinePanel = uipanel(app.MainGridLayout);
            app.CloseLinePanel.Title = 'Closing Line Algorithm';
            app.CloseLineGridLayout = uigridlayout(app.CloseLinePanel);
            app.CloseLineDropDown = uidropdown(app.CloseLineGridLayout);
            app.CloseLineDropDown.Items = {'Geodesic Active Contour'};
            app.CloseLineDropDown.ValueChangedFcn = createCallbackFcn(app, @CloseLineDropDownValueChanged, true);
            app.CloseLineDropDown.Value = 'Geodesic Active Contour';
            app.CloseLineHyperlink = uihyperlink(app.CloseLineGridLayout);
            app.CloseLineHyperlink.HyperlinkClickedFcn = createCallbackFcn(app, @CloseLineHyperlinkClicked, true);
            app.CloseLineHyperlink.Text = 'Help...';
            % Show the figure after all components are created
            app.AdvancedFeaturesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_advancedFeature(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AdvancedFeaturesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AdvancedFeaturesUIFigure)
        end
    end
end