classdef app_input < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        InputGridLayout  matlab.ui.container.GridLayout
        Panel            matlab.ui.container.Panel
        GridLayout       matlab.ui.container.GridLayout
        NextButton       matlab.ui.control.Button
    end

    
    properties (Access = private)
        callingApp % Main app
        algorithm % Algorithm to execute
        segmentations % The segmentations to fuse
        componentsList % List of components used to get the inputs
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, segmentations, algorithm)
            app.callingApp = caller;
            app.algorithm = algorithm;
            app.segmentations = segmentations;
            app.componentsList = {};

            inputsFile = strcat(algorithm,'.json');
            fileContent = fileread(inputsFile);
            inputsList = jsondecode(fileContent).inputs;

            if ~iscell(inputsList)
                tmp = {};
                for i = 1:size(inputsList, 1)
                    tmp = [tmp; inputsList(1)];
                end
                inputsList = tmp;
            end

            s = size(inputsList, 1);
            n = 1;
            if s > 1
                for i = 1:s-1
                    app.GridLayout.RowHeight = [app.GridLayout.RowHeight, 'fit'];
                end
            end
 
            for i = 1:length(inputsList)
                input = inputsList{i};
                switch input.type

                    case 'DropDown'
                        DropDownLabel = uilabel(app.GridLayout);
                        DropDownLabel.HorizontalAlignment = 'center';
                        DropDownLabel.FontColor = [1 1 1];
                        DropDownLabel.Layout.Row = n;
                        DropDownLabel.Layout.Column = 1;
                        DropDownLabel.Text = input.name;

                        DropDown = uidropdown(app.GridLayout);
                        DropDown.Items = input.value;
                        DropDown.Layout.Row = n;
                        DropDown.Layout.Column = 2;
                        app.componentsList = [app.componentsList, DropDown];

                    case 'Text'
                        EditFieldLabel = uilabel(app.GridLayout);
                        EditFieldLabel.HorizontalAlignment = 'center';
                        EditFieldLabel.FontColor = [1 1 1];
                        EditFieldLabel.Layout.Row = n;
                        EditFieldLabel.Layout.Column = 1;
                        EditFieldLabel.Text = input.name;

                        EditField = uieditfield(app.GridLayout);
                        EditField.Layout.Row = n;
                        EditField.Layout.Column = 2;
                        app.componentsList = [app.componentsList , EditField];

                    case 'Check'
                        CheckBox = uicheckbox(app.GridLayout);
                        CheckBox.Text = input.name;
                        CheckBox.FontColor = [1 1 1];
                        CheckBox.Layout.Row = n;
                        CheckBox.Layout.Column = 2;
                        
                        app.componentsList = [app.componentsList , CheckBox];

                    case 'Slider'

                end
                n = n + 1;
            end

            % Center the dialog box
            app.UIFigure.Visible = "off";
            movegui(app.UIFigure, 'center');
            app.UIFigure.Visible = "on";
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            % Get all the parameters
            arg = {};
            for component = app.componentsList
                arg = [arg, component.Value];
            end
            
            % Execute the fusion
            res = computeFusion(app.segmentations, app.algorithm, arg{:});
            resultApp = app_result(res);

            % Pass the reference of the new result app to the main app
            app.callingApp.addResultApp(resultApp);
            
            % Close the app and re-enable main app
            app.UIFigureCloseRequest();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.callingApp.enableAllButtons();
            
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.InputGridLayout = uigridlayout(app.UIFigure);
            app.NextButton = uibutton(app.InputGridLayout, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Tag = 'p2_back';
            app.NextButton.Text = 'Next';
            app.Panel = uipanel(app.InputGridLayout);
            app.GridLayout = uigridlayout(app.Panel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_input(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end