classdef app_main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TDSFTUIFigure           matlab.ui.Figure
        MainGridLayout          matlab.ui.container.GridLayout
        FinalPanel              matlab.ui.container.Panel
        GridLayout              matlab.ui.container.GridLayout
        About                   matlab.ui.control.Hyperlink
        Docs                    matlab.ui.control.Hyperlink
        NextButton              matlab.ui.control.Button
        AlgorithmPanel          matlab.ui.container.Panel
        AlgorithmGridLayout     matlab.ui.container.GridLayout
        AlgorithmDropDown       matlab.ui.control.DropDown
        AlgorithmDropDownLabel  matlab.ui.control.Label
        SegPanel                matlab.ui.container.Panel
        SegGridLayout           matlab.ui.container.GridLayout
        SegHandlerGridLayout    matlab.ui.container.GridLayout
        Hyperlink               matlab.ui.control.Hyperlink
        UploadButton            matlab.ui.control.Button
        OpenButton              matlab.ui.control.Button
        RemoveButton            matlab.ui.control.Button
        SegListBox              matlab.ui.control.ListBox
    end

    
    properties (Access = private)
        %CANNOT BE TRUE BOTH DENSE AND INTERNAL, if the object is dense there is no internal line.
        dense % If true, dense option selected
        internalLine % If true, internal line option selected
       
        fileNames % File names
        bwImages % Black and white images
        segmentations % Segmentations
        selectedSegmentations % Index of the selected segmentations
        inputApp % inputs app
        imageApps % array containing all the imageApp opened
        aboutApp % about app
        resultApps % result app
        advancedFeatureApp % advanced feature app  

        EMPTY_STRING % Empty segmentation list string
    end
    
    methods (Access = private)
        
        function result = isEnabled(~, button)
            enableString = string(button.Enable);
            result = strcmpi(enableString, "on");
        end

        function disableAllButtons(app)
            app.NextButton.Enable = "off";
            app.OpenButton.Enable = "off";
            app.RemoveButton.Enable = "off";
            app.UploadButton.Enable = "off";
        end    

        function loadAlgorithms(app)
            algorithmsNames = dir(fullfile('api','algorithms','*.m'));

            % Needed for the standalone version
            algorithmsNames = [algorithmsNames, dir('algorithm_*.m')];

            algorithmsNames = arrayfun(@(v) extractBetween(v.name, 'algorithm_','.m'), algorithmsNames);
            algorithmsNames = arrayfun(@(v) app.getPrettyAlgorithmName(string(v)), algorithmsNames);
            app.AlgorithmDropDown.Items = algorithmsNames;
        end

        function res = getPrettyAlgorithmName(~, name)
            res = regexprep(name, '([a-z])([A-Z])', '$1 $2');
        end

        function res = getCamelCaseName(~, name)
            res = regexprep(name, '([a-z]) ([A-Z])', '$1$2');
        end

        function result = removeFileExtension(~, name)
            expression = '.*(?=\.)';
            result = regexpi(name, expression, 'match');
            result = string(result);
        end
        

    end
    
    methods (Access = public)
        
        function EnableAllButtons(app)
            app.NextButton.Enable = "on";
            app.OpenButton.Enable = "on";
            app.RemoveButton.Enable = "on";
            app.UploadButton.Enable = "on";
        end
        
        function EnableAbout(app)
            app.About.Enable = "on";
        end

        function setDense(app, value)
            if app.internalLine && value
                uiconfirm(app.TDSFTUIFigure, "CANNOT SELECT BOTH DENSE AND INTERNAL, if the object is dense there is no internal line.", "ERROR", "Options", "OK", "Icon", "error");
            else
                app.dense = value;
                if value
                    disp("Dense feature selected");
                else
                    disp("Dense feature deselected");
                end
            end
        end

        function setInternalLine(app, value)
            if app.dense && value
                uiconfirm(app.TDSFTUIFigure, "CANNOT SELECT BOTH DENSE AND INTERNAL, if the object is dense there is no internal line.", "ERROR", "Options", "OK", "Icon", "error");
            else
                app.internalLine = value;
                if value
                    disp("Internal line feature selected");
                else
                    disp("Internal line feature deselected");
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Center the gui
            app.TDSFTUIFigure.Visible = "off";
            movegui(app.TDSFTUIFigure, "center");
            app.TDSFTUIFigure.Visible = "on";
            
            % Run startup script to load all the directories to the path
            runStartup();

            % Load segmentation fusion algorithms 
            app.loadAlgorithms();
            
            % Disable segmentations handlers buttons
            app.RemoveButton.Enable = "off";
            app.OpenButton.Enable = "off";
            app.NextButton.Enable = "off";
            app.SegListBox.Enable = "off";
            
            % Initialize variables
            app.dense = false;
            app.internalLine = false;
            app.fileNames = {};
            app.bwImages = {};
            app.segmentations = {};
            app.imageApps = [];
            app.resultApps = [];
            app.selectedSegmentations = [];
            app.EMPTY_STRING = "No segmentation loaded";
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            algorithm = app.AlgorithmDropDown.Value;
            algorithm = app.getCamelCaseName(algorithm);
            res = computeFusion(app.segmentations, algorithm);
%             try 
%                 res = computeFusion(app.segmentations, algorithm);
%             catch ME
%                 uialert(app.TDSFTUIFigure, ME.message, 'Empty Input');
%             end
            resultApp = app_result(res);

            % Get a reference to the app to be aple to delete it
            app.resultApps = [app.resultApps, resultApp];
            %app.inputApp = inputTDSFT(app);
            %app.disableAllButtons();
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            % Check if there is a selected segmentation
            if isempty(app.selectedSegmentations)
                return
            end
    
            % Get all the data related to the selected image
            inName = app.fileNames{app.selectedSegmentations};
            inBwImg = app.bwImages{app.selectedSegmentations};
            inSeg = app.segmentations{app.selectedSegmentations};

            % Open the image's app
            fprintf('Image opened: %s\n', string(inName));          
            imageApp = app_image(inName, inBwImg, inSeg);

            % Get a reference to the app to be aple to delete it
            app.imageApps = [app.imageApps, imageApp];
        end

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            % Upload segmentations
            % Change here if more image formats are needed
            [inputNames, inputPath] = uigetfile( ...
                {'*.jpg;*.png;*.jpeg;*.bmp;*.tif;*.tiff;*.gif'}, ...
                'Select one or more segmentations', ...
                'MultiSelect','on');

            % Reopen app's window
            % Solve a known issue of the function `uigetfile` (see: https://it.mathworks.com/matlabcentral/answers/296305-appdesigner-window-ends-up-in-background-after-uigetfile)
             figure(app.TDSFTUIFigure);
            
            % Check if there are any inputs
            if isequal(inputNames, 0) || isequal(inputPath, 0) 
                fprintf('Selected Cancel\n');
                return;
            else

                if ~iscell(inputNames)
                    inputNames = {inputNames};
                end
                for name = inputNames
                    try 
                        fullname = fullfile(inputPath, string(name));
                        img = imread(fullname);
                        
                        % Process image
                        if app.dense && app.internalLine
                            ME = MException('Wrong Parameters:computeFusion', 'CANNOT SELECT BOTH DENSE AND INTERNAL, if the object is dense there is no internal line.');
                            throw(ME);
                        end
                        [bw, seg, wrn] = processImage(img, app.dense, app.internalLine);

                        if wrn
                            message = "The input image - " + name + " - had more than one channel but was not an rgb image. Used only the first channel";
                            uiconfirm(app.TDSFTUIFigure, message, "WARNING", "Options", "OK", "Icon", "warning");
                        end

                        % Store inputs
                        fprintf('Image selected: %s\n', string(name));

                        %Remove file extension
                        minimalName = app.removeFileExtension(name);
                        app.fileNames = [app.fileNames, minimalName];
                        app.bwImages = [app.bwImages, bw];
                        app.segmentations = [app.segmentations, seg];
                    % Catch error and discard image
                    catch ME
                        fprintf('Image discarded: %s\n', string(name));
                        uialert(app.TDSFTUIFigure, ME.message, 'Image Discarded');
                    end
                end
                
                % Update ListBox
                app.SegListBox.Items = cellstr(app.fileNames);

                if ~app.isEnabled(app.SegListBox)
                    app.SegListBox.Enable = "on";
                end

                if length(app.fileNames) >= 2
                    app.NextButton.Enable = "on";
                end
            end
        end

        % Clicked callback: SegListBox
        function SegListBoxClicked(app, event)
            % Check if there is at least one segmentation loaded
            if isempty(app.segmentations)
                return
            end
            
            % Get selected segmentation's index
            item = event.InteractionInformation.Item;
            app.selectedSegmentations = item;

            % Enable buttons
            if ~app.isEnabled(app.RemoveButton)
                app.RemoveButton.Enable ="on";
            end
            if ~app.isEnabled(app.OpenButton)
                app.OpenButton.Enable ="on";
            end
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            % Check if there is a segmentation selected
            if app.selectedSegmentations
                % Remove the segmentation
                app.fileNames(app.selectedSegmentations) = [];
                app.bwImages(app.selectedSegmentations) = [];
                app.segmentations(app.selectedSegmentations) = [];

                % Add empty string message it there are is no segmentation left
                if isempty(app.fileNames)
                    app.SegListBox.Items = [app.EMPTY_STRING];
                    app.SegListBox.Enable = "off";
                else
                    app.SegListBox.Items = cellstr(app.fileNames);
                end

                if app.isEnabled(app.NextButton) && length(app.fileNames) < 2
                    app.NextButton.Enable = "off";
                end

                % Remove selected segmentations and disable buttons
                app.selectedSegmentations = [];
                app.OpenButton.Enable = "off";
                app.RemoveButton.Enable = "off";
            end
        end

        % Close request function: TDSFTUIFigure
        function TDSFTUIFigureCloseRequest(app, event)
            delete(app.inputApp);
            delete(app.aboutApp);
            delete(app.advancedFeatureApp);

            for imageApp = app.imageApps
                delete(imageApp);
            end

            for resultApp = app.resultApps       
                delete(resultApp);
            end

            delete(app);
        end

        % Callback function: About
        function AboutClicked(app, event)
            app.About.Enable = "off";
            app.aboutApp = app_about(app);
        end

        % Callback function: Hyperlink
        function HyperlinkClicked(app, event)
            app.advancedFeatureApp = app_advancedFeature(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TDSFTUIFigure = uifigure('Visible', 'off');
            app.TDSFTUIFigure.Name = 'TDSFT';
            app.TDSFTUIFigure.CloseRequestFcn = createCallbackFcn(app, @TDSFTUIFigureCloseRequest, true);
            app.MainGridLayout = uigridlayout(app.TDSFTUIFigure);
            app.SegPanel = uipanel(app.MainGridLayout);
            app.SegPanel.Title = 'Segmentations';
            app.SegGridLayout = uigridlayout(app.SegPanel);
            app.SegListBox = uilistbox(app.SegGridLayout);
            app.SegListBox.Items = {'No segmentation loaded'};
            app.SegListBox.ClickedFcn = createCallbackFcn(app, @SegListBoxClicked, true);
            app.SegListBox.Value = {};
            app.SegHandlerGridLayout = uigridlayout(app.SegGridLayout);
            app.RemoveButton = uibutton(app.SegHandlerGridLayout, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = 'Remove';
            app.OpenButton = uibutton(app.SegHandlerGridLayout, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.UploadButton = uibutton(app.SegHandlerGridLayout, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = 'Upload';
            app.Hyperlink = uihyperlink(app.SegHandlerGridLayout);
            app.Hyperlink.HyperlinkClickedFcn = createCallbackFcn(app, @HyperlinkClicked, true);
            app.Hyperlink.Text = ['Advanced' newline 'Features'];
            app.AlgorithmPanel = uipanel(app.MainGridLayout);
            app.AlgorithmGridLayout = uigridlayout(app.AlgorithmPanel);
            app.AlgorithmDropDownLabel = uilabel(app.AlgorithmGridLayout);
            app.AlgorithmDropDownLabel.Text = 'Algorithm';
            app.AlgorithmDropDown = uidropdown(app.AlgorithmGridLayout);
            app.AlgorithmDropDown.Items = {};
            app.AlgorithmDropDown.Value = {};
            app.FinalPanel = uipanel(app.MainGridLayout);
            app.GridLayout = uigridlayout(app.FinalPanel);
            app.NextButton = uibutton(app.GridLayout, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.Docs = uihyperlink(app.GridLayout);
            app.Docs.URL = 'https://github.com/UniBoDS4H/2DSFT/';
            app.Docs.Text = 'Docs';
            app.About = uihyperlink(app.GridLayout);
            app.About.HyperlinkClickedFcn = createCallbackFcn(app, @AboutClicked, true);
            app.About.Text = 'About';
            % Show the figure after all components are created
            app.TDSFTUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TDSFTUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TDSFTUIFigure)
        end
    end
end