classdef app_result < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ResultUIFigure        matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        SavePanel             matlab.ui.container.Panel
        SaveGridLayout        matlab.ui.container.GridLayout
        SaveButton            matlab.ui.control.Button
        SliderPanel           matlab.ui.container.Panel
        SliderGridLayout      matlab.ui.container.GridLayout
        ThicknessSlider       matlab.ui.control.Slider
        ThicknessSliderLabel  matlab.ui.control.Label
        Panel                 matlab.ui.container.Panel
        ImageGridLayout       matlab.ui.container.GridLayout
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        image % image to display
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, image)
            app.ResultUIFigure.Visible ="off";
            movegui(app.ResultUIFigure, "center");
            app.ResultUIFigure.Visible ="on";

            app.image = image;

            app.UIAxes.PositionConstraint = 'innerposition';
            app.UIAxes.Interactions = zoomInteraction;

            imshow(app.image, [], 'Parent', app.UIAxes);
        end

        % Value changed function: ThicknessSlider
        function ThicknessSliderValueChanged(app, event)
            % Apply dilation on the starting segmentation
            value = app.ThicknessSlider.Value;

            % Round to nearest integer
            value = round(value);
            dilImg = imdilate(app.image, strel('diamond', value));
            imshow(dilImg, [], 'Parent', app.UIAxes);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            file = uiputfile( ...
                {'*.jpg;*.png;*.jpeg;*.bmp;*.tif;*.tiff;*.gif'}, ...
                'Save to File', ...
                'result.png');
            if file
                imwrite(app.image, file);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ResultUIFigure = uifigure('Visible', 'off');
            app.ResultUIFigure.Name = 'Result';
            app.GridLayout = uigridlayout(app.ResultUIFigure);
            app.Panel = uipanel(app.GridLayout);
            app.ImageGridLayout = uigridlayout(app.Panel);
            app.UIAxes = uiaxes(app.ImageGridLayout);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.SliderPanel = uipanel(app.GridLayout);
            app.SliderGridLayout = uigridlayout(app.SliderPanel);
            app.ThicknessSliderLabel = uilabel(app.SliderGridLayout);
            app.ThicknessSliderLabel.Text = 'Thickness';
            app.ThicknessSlider = uislider(app.SliderGridLayout);
            app.ThicknessSlider.Limits = [1 19];
            app.ThicknessSlider.MajorTicks = [1 3 5 7 9 11 13 15 17 19];
            app.ThicknessSlider.ValueChangedFcn = createCallbackFcn(app, @ThicknessSliderValueChanged, true);
            app.ThicknessSlider.MinorTicks = [2 4 6 8 10 12 14 16 18];
            app.ThicknessSlider.Value = 1;
            app.SavePanel = uipanel(app.GridLayout);
            app.SaveGridLayout = uigridlayout(app.SavePanel);
            app.SaveButton = uibutton(app.SaveGridLayout, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.ResultUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_result(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ResultUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ResultUIFigure)
        end
    end
end