classdef app_advancedFeatures < matlab.apps.AppBase
    %APP_ADVANCEDFEATURES Advanced Features app of TDSFT tool.
    %   AUTHOR: Lorenzo Drudi (E-mail: lorenzodrudi11@gmail.com)
    %   NAME:   TDSFT (version 1.0)
    %   
    %   Advanced feature window for TDSFT tool.
    %   It permits you to choose the following advanced features:
    %   - the presence of dense input segmentations;
    %   - line type for input segmentations of more than one pixel;
    %   - closing algorithm to use if the result segmentation is not
    %   already closed.
    %   
    %   Parameters:
    %     - caller:
    %         the calling app;
    %     - dense (bool):
    %         the current value of the dense property;
    %     - onePixelLine (see OnePixelLineTypes.m):
    %         the current value of the one-pixel line property;
    %     - closingAlgorithm (see api/closingLineAlgorithms folder):
    %         the current closing algorithm.

    % Properties that correspond to app components
    properties (Access = public)
        AdvancedFeaturesUIFigure   matlab.ui.Figure
        MainGridLayout             matlab.ui.container.GridLayout
        CloseLinePanel             matlab.ui.container.Panel
        ClosingLineGridLayout      matlab.ui.container.GridLayout
        ClosingLineHyperlink       matlab.ui.control.Hyperlink
        ClosingLineDropDown        matlab.ui.control.DropDown
        OnePixelLinePanel          matlab.ui.container.Panel
        OnePixelGridLayout         matlab.ui.container.GridLayout
        OnePixelDropDown           matlab.ui.control.DropDown
        OnePixelHyperlink          matlab.ui.control.Hyperlink
        DenseHyperlink             matlab.ui.control.Hyperlink
        DenseSegmentationCheckBox  matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        callingApp % The calling app
    end
    
    methods (Access = private)
        
        % Load close line algorithms.
        % (see api/closingLineAlgorithms folder for more details).
        function loadClosingLineAlgorithms(app)
            closingAlgorithmsNames = dir(fullfile("api", "closingLineAlgorithms",...
                strcat(Constants.CLOSING_NAMES_FILE_ROOT, "*", Constants.SRC_EXTENSION)));

            closingAlgorithmsNames = arrayfun(@(v) extractBetween(v.name,...
                Constants.CLOSING_NAMES_FILE_ROOT, Constants.SRC_EXTENSION), closingAlgorithmsNames);
            closingAlgorithmsNames = arrayfun(@(v) StringsUtils.fromCamelCaseToSpacedString(string(v)), closingAlgorithmsNames);
            app.ClosingLineDropDown.Items = string(closingAlgorithmsNames);
        end

        % Load one-pixel line types from the enumeration.
        function loadOnePixelLineTypes(app)
            enumArray = enumeration("OnePixelLineTypes");
            n = length(enumArray);
            onePixelLineTypes = strings(n, 1);
            for i = 1:n
                s = enumArray(i).string;
                s = strcat(upper(s(1)), lower(s(2:end)));
                onePixelLineTypes(i) = s;
            end
            app.OnePixelDropDown.Items = onePixelLineTypes;
        end 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, dense, onePixelLine, closingLineAlgorithm)
            app.AdvancedFeaturesUIFigure.Visible = "off";
            movegui(app.AdvancedFeaturesUIFigure, "center");
            app.AdvancedFeaturesUIFigure.Visible = "on";

            % Store inputs.
            app.callingApp = caller;

            % Load closing line algorithms.
            app.loadClosingLineAlgorithms();

            % Load one-pixel line types.
            app.loadOnePixelLineTypes();

            % Set values.
            if ~isempty(dense)
                % If dense set OnePixelDropDown property
                % and disable the one pixel dropdown.
                if dense
                    onePixelValue = OnePixelLineTypes.EXTERNAL.string;
                    onePixelValue = strcat(upper(onePixelValue(1)), lower(onePixelValue(2:end)));
                    app.OnePixelDropDown.Value = onePixelValue;
                    app.OnePixelDropDown.Enable = "off";
                end

                app.DenseSegmentationCheckBox.Value = dense;
            end

            % If dense is true OnePixelDropDown is already set.
            if ~isempty(onePixelLine) && ~dense
                % If one pixel line is different from External
                % disable dense checkbox.
                if ~strcmp(onePixelLine, OnePixelLineTypes.EXTERNAL.string)
                    app.DenseSegmentationCheckBox.Value = false;
                    app.DenseSegmentationCheckBox.Enable = "off";
                end

                onePixelLine = strcat(upper(onePixelLine(1)), lower(onePixelLine(2:end)));
                app.OnePixelDropDown.Value = onePixelLine;
            end

            if ~isempty(closingLineAlgorithm)
                s = erase(closingLineAlgorithm, Constants.CLOSING_NAMES_FILE_ROOT);
                s = StringsUtils.fromCamelCaseToSpacedString(s);
                app.ClosingLineDropDown.Value = s;
            end
            drawnow
        end

        % Value changed function: DenseSegmentationCheckBox
        function DenseSegmentationCheckBoxValueChanged(app, event)
            value = app.DenseSegmentationCheckBox.Value;
            
            % Handle incompatibility.
            if value
                app.OnePixelDropDown.Enable = "off";
            else
                app.OnePixelDropDown.Enable = "on";
            end
            drawnow

            app.callingApp.setDense(value);
        end

        % Callback function: DenseHyperlink
        function DenseHyperlinkClicked(app, event)
            uiconfirm(app.AdvancedFeaturesUIFigure, "True if a dense object is present and more tests are required, false otherwise." + ...
                newline + "It adds more check to the segmentation to be able to better recognize open lines and close dense lines." + ...
                newline + "Be careful, it is difficult to recognize a dense object from an open line of more than one pixel." + ...
                newline + "Since an open line of more than one pixel is, by definition, a dense object we use some statistics to " + ...
                "recognize a dense object from an open line of more than one pixel.", ...
                "Dense Segmentation", "Options", "OK", "Icon", "info");
        end

        % Callback function: OnePixelHyperlink
        function OnePixelHyperlinkClicked(app, event)
            uiconfirm(app.AdvancedFeaturesUIFigure, "Select the line you want to use for input segmentations of more than one pixel." + ...
                newline + "The options are: external, internal, and middle." + newline + "Only external line is compatible " + ...
                "with `dense` option.", "One Pixel Line", "Options", "OK", "Icon", "info");
        end

        % Value changed function: OnePixelDropDown
        function OnePixelDropDownValueChanged(app, event)
            value = app.OnePixelDropDown.Value;
            
            % Handle incompatibility.
            if ~strcmp(value, app.OnePixelDropDown.Items(1))
                app.DenseSegmentationCheckBox.Enable = "off";
            else
                app.DenseSegmentationCheckBox.Enable = "on";
            end
            drawnow

            app.callingApp.setOnePixelLine(lower(value));
        end

        % Value changed function: ClosingLineDropDown
        function ClosingLineDropDownValueChanged(app, event)
            value = app.ClosingLineDropDown.Value;

            algorithmFullname = StringsUtils.fromSpacedToClosingAlgorithmFullName(value);
            app.callingApp.setClosingLineAlgorithm(algorithmFullname);
        end

        % Callback function: ClosingLineHyperlink
        function ClosingLineHyperlinkClicked(app, event)
            uiconfirm(app.AdvancedFeaturesUIFigure, "Select the algorithm you want to use to close the resulting " + ...
                "segmentation (if it is not already closed) after the fusion process.", ...
                "Close Line Algorithm", "Options", "OK", "Icon", "info");     
        end

        % Close request function: AdvancedFeaturesUIFigure
        function AdvancedFeaturesUIFigureCloseRequest(app, event)
            app.callingApp.enableAdvancedFeatures();
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.AdvancedFeaturesUIFigure = uifigure('Visible', 'off');
            app.AdvancedFeaturesUIFigure.Name = 'Advanced Features';
            app.AdvancedFeaturesUIFigure.Icon = fullfile(pathToMLAPP, 'logo', 'logo_nobg.png');
            app.AdvancedFeaturesUIFigure.CloseRequestFcn = createCallbackFcn(app, @AdvancedFeaturesUIFigureCloseRequest, true);
            app.MainGridLayout = uigridlayout(app.AdvancedFeaturesUIFigure);
            app.DenseSegmentationCheckBox = uicheckbox(app.MainGridLayout);
            app.DenseSegmentationCheckBox.ValueChangedFcn = createCallbackFcn(app, @DenseSegmentationCheckBoxValueChanged, true);
            app.DenseSegmentationCheckBox.Text = 'Dense Segmentation';
            app.DenseHyperlink = uihyperlink(app.MainGridLayout);
            app.DenseHyperlink.HyperlinkClickedFcn = createCallbackFcn(app, @DenseHyperlinkClicked, true);
            app.DenseHyperlink.Text = 'Help ...';
            app.OnePixelLinePanel = uipanel(app.MainGridLayout);
            app.OnePixelLinePanel.Title = 'One Pixel Line';
            app.OnePixelGridLayout = uigridlayout(app.OnePixelLinePanel);
            app.OnePixelHyperlink = uihyperlink(app.OnePixelGridLayout);
            app.OnePixelHyperlink.HyperlinkClickedFcn = createCallbackFcn(app, @OnePixelHyperlinkClicked, true);
            app.OnePixelHyperlink.Text = 'Help...';
            app.OnePixelDropDown = uidropdown(app.OnePixelGridLayout);
            app.OnePixelDropDown.Items = {};
            app.OnePixelDropDown.ValueChangedFcn = createCallbackFcn(app, @OnePixelDropDownValueChanged, true);
            app.OnePixelDropDown.Value = {};
            app.CloseLinePanel = uipanel(app.MainGridLayout);
            app.CloseLinePanel.Title = 'Closing Line Algorithm';
            app.ClosingLineGridLayout = uigridlayout(app.CloseLinePanel);
            app.ClosingLineDropDown = uidropdown(app.ClosingLineGridLayout);
            app.ClosingLineDropDown.Items = {};
            app.ClosingLineDropDown.ValueChangedFcn = createCallbackFcn(app, @ClosingLineDropDownValueChanged, true);
            app.ClosingLineDropDown.Value = {};
            app.ClosingLineHyperlink = uihyperlink(app.ClosingLineGridLayout);
            app.ClosingLineHyperlink.HyperlinkClickedFcn = createCallbackFcn(app, @ClosingLineHyperlinkClicked, true);
            app.ClosingLineHyperlink.Text = 'Help...';
            % Show the figure after all components are created
            app.AdvancedFeaturesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_advancedFeatures(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AdvancedFeaturesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AdvancedFeaturesUIFigure)
        end
    end
end