classdef app_image < matlab.apps.AppBase
    %APP_IMAGE Image app of TDSFT tool.
    %   AUTHOR: Lorenzo Drudi (E-mail: lorenzodrudi11@gmail.com)
    %   NAME:   TDSFT (version 1.0)
    %   
    %   App used to show the input images of the TDSFT tool.
    %   It permits to show:
    %   - the black-and-white original image;
    %   - the one-pixel segmentation with the possibility to apply a 
    %     dilation on it.
    %   
    %   Parameters:
    %     - inName (String):
    %         the name of the image;
    %     - inBwImg (Matrix [height, width]):
    %         the black-and-white image;
    %     - inSeg (Matrix [height, width]):
    %         the one-pixel segmentation.

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        MainGridLayout        matlab.ui.container.GridLayout
        ThicknessPanel        matlab.ui.container.Panel
        ThicknessGridLayout   matlab.ui.container.GridLayout
        ThicknessSlider       matlab.ui.control.Slider
        ThicknessSliderLabel  matlab.ui.control.Label
        ShowGroup             matlab.ui.container.ButtonGroup
        Segmentation          matlab.ui.control.RadioButton
        Blackandwhite         matlab.ui.control.RadioButton
        ImagePanel            matlab.ui.container.Panel
        ImageGridLayout       matlab.ui.container.GridLayout
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        name  % Image name
        bwImg % Black and white image
        seg   % Segmentation of the object in the image
    end

    methods (Access = private)
        
        function disableThickness(app)
            app.ThicknessSlider.Enable = "off";
            app.ThicknessSliderLabel.Enable = "off";
        end

        function enableThickness(app)
            app.ThicknessSlider.Enable = "on";
            app.ThicknessSliderLabel.Enable = "on";
        end
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, inName, inBwImg, inSeg)
            % Center the dialog box.
            app.UIFigure.Visible = "off";
            movegui(app.UIFigure, "center");
            app.UIFigure.Visible = "on";

            
            % Store input params.
            app.name = inName;
            app.bwImg = inBwImg;
            app.seg = inSeg;
            
            app.UIFigure.Name= app.name;
            app.disableThickness();
            app.UIAxes.Interactions = zoomInteraction;
            
            % Show, as default, the original image.
            imshow(app.bwImg, [], "Parent", app.UIAxes);
            drawnow
        end

        % Value changed function: ThicknessSlider
        function ThicknessSliderValueChanged(app, event)
            % Get slider value.
            value = app.ThicknessSlider.Value;
    
            % Re-adapt the value because level 1 of thickness
            % is the original one (so no dilation).
            value = value - 1;

            % Round to nearest integer.
            value = round(value);

            % Apply the dilation with the mapped value.
            dilImg = imdilate(app.seg, strel("diamond", value));
            imshow(dilImg, [], "Parent", app.UIAxes);
            drawnow
        end

        % Selection changed function: ShowGroup
        function ShowGroupSelectionChanged(app, event)
            selectedButton = app.ShowGroup.SelectedObject;
            switch selectedButton
                case app.Blackandwhite
                    app.disableThickness();
                    imshow(app.bwImg, [], "Parent", app.UIAxes);
                case app.Segmentation
                    imshow(app.seg, [], "Parent", app.UIAxes);
                    app.enableThickness();
            end
            drawnow
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'logo', 'logo_nobg.png');
            app.MainGridLayout = uigridlayout(app.UIFigure);
            app.ImagePanel = uipanel(app.MainGridLayout);
            app.ImageGridLayout = uigridlayout(app.ImagePanel);
            app.UIAxes = uiaxes(app.ImageGridLayout);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.ShowGroup = uibuttongroup(app.MainGridLayout);
            app.ShowGroup.SelectionChangedFcn = createCallbackFcn(app, @ShowGroupSelectionChanged, true);
            app.ShowGroup.Title = 'Show:';
            app.Blackandwhite = uiradiobutton(app.ShowGroup);
            app.Blackandwhite.Text = 'Black-and-white';
            app.Blackandwhite.Value = true;
            app.Segmentation = uiradiobutton(app.ShowGroup);
            app.Segmentation.Text = 'Segmentation';
            app.ThicknessPanel = uipanel(app.MainGridLayout);
            app.ThicknessGridLayout = uigridlayout(app.ThicknessPanel);
            app.ThicknessSliderLabel = uilabel(app.ThicknessGridLayout);
            app.ThicknessSliderLabel.Text = 'Thickness';
            app.ThicknessSlider = uislider(app.ThicknessGridLayout);
            app.ThicknessSlider.Limits = [1 17];
            app.ThicknessSlider.MajorTicks = [1 3 5 7 9 11 13 15 17];
            app.ThicknessSlider.ValueChangedFcn = createCallbackFcn(app, @ThicknessSliderValueChanged, true);
            app.ThicknessSlider.MinorTicks = [0 2 4 6 8 10 12 14 16];
            app.ThicknessSlider.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_image(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end