classdef app_input < matlab.apps.AppBase
    %APP_INPUT Input app of the TDSFT tool.
    %   AUTHOR: Lorenzo Drudi (E-mail: lorenzodrudi11@gmail.com)
    %   NAME:   TDSFT (version 1.0)
    %   
    %   Custom app for user inputs required by the algorithms.
    %   See the docs for more details about how to use it.
    %   
    %   Parameters:
    %   - caller:
    %       the calling app;
    %   - algorithm:
    %       the fusion algorithm to use;
    %   - segmentations:
    %       the segmentations to fuse. 

    % Properties that correspond to app components
    properties (Access = public)
        ParametersUIFigure  matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        NextPanel           matlab.ui.container.Panel
        GridLayout2         matlab.ui.container.GridLayout
        NextButton          matlab.ui.control.Button
        Panel               matlab.ui.container.Panel
        InputGridLayout     matlab.ui.container.GridLayout
    end

    
    properties (Access = private)
        callingApp          % Main app
        fontColor           % Font color
        linkColor           % Hyperlink color
        nextClicked         % Put to true when next button is clicked

        % List of components used to get the inputs.
        % (Each element is a pair: {components, optional}).
        componentsList 
    end
    
    methods (Access = private)
        
        % Callbacks for the help hyperlink of the components.
        function displayHelpBox(app, ~, ~, name, text)
            uiconfirm(app.ParametersUIFigure, text, strcat("Help ", name), "Options", "OK", "Icon", "info");
        end

        % Create a label component.
        function createLabel(app, row, col, text)
            label = uilabel(app.InputGridLayout);
            label.HorizontalAlignment = "center";
            label.FontColor = app.fontColor;
            label.Layout.Row = row;
            label.Layout.Column = col;
            label.Text = text;
            label.FontSize = 14;
        end

        % Create a numeric edit field component.
        function numericEditField = createNumericEditField(app, row, col, limits, value)
            numericEditField = uieditfield(app.InputGridLayout, "numeric");
            numericEditField.Limits = [limits(1) limits(2)];
            numericEditField.Layout.Row = row;
            numericEditField.Layout.Column = col;
            numericEditField.Value = value;
            numericEditField.FontSize = 14;
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, algorithm, segmentations)
            % Center the gui
            app.ParametersUIFigure.Visible = "off";
            movegui(app.ParametersUIFigure, "center");
            app.ParametersUIFigure.Visible = "on";
            
            app.callingApp = caller;
            app.componentsList = {};
            app.fontColor = [1 1 1];
            app.linkColor = [0 0.4471 0.7412];
            app.nextClicked = false;

            % Read the json file for the inputs of the specified algorithm.
            inputsFile = strcat(algorithm, Constants.INPUT_FILE_EXTENSION);
            inputsFile = fullfile("api", "fusionAlgorithms", "inputs", inputsFile);
            fileContent = fileread(inputsFile);
            inputsList = jsondecode(fileContent).inputs;

            % If there is only one input or all the inputs are the same
            % the result of jsondecode isn't a cell array.
            % To handle always the same object convert to cell array. 
            nParam = length(inputsList);
            if ~iscell(inputsList)
                tmp = cell(nParam);
                for i = 1:nParam
                    tmp{i} = inputsList(i);
                end
                inputsList = tmp;
            end
            
            n = 1;
            if nParam > 1
                for i = 1:nParam-1
                    app.InputGridLayout.RowHeight = [app.InputGridLayout.RowHeight, "fit"];
                end
            end
            
            app.componentsList = cell(nParam, 1);
            for i = 1:nParam
                input = inputsList{i};
                
                % Create label in the 1st column.
                app.createLabel(n, 1, input.name);

                % Create component in the 2nd column.
                switch input.type

                    case "DropDown"
                        % Create a dropdown with values specified in the
                        % json.
                        dropDown = uidropdown(app.InputGridLayout);
                        dropDown.Items = input.value;
                        dropDown.Layout.Row = n;
                        dropDown.Layout.Column = 2;
                        dropDown.FontSize = 14;

                        app.componentsList(n) = {dropDown};

                    case "Text"
                        % Create a text edit field of a text input.
                        editField = uieditfield(app.InputGridLayout);
                        editField.Layout.Row = n;
                        editField.Layout.Column = 2;
                        editField.FontSize = 14;

                        app.componentsList(n) = {editField};

                    case "Check"
                        % Create a check box for a boolean input.
                        checkBox = uicheckbox(app.InputGridLayout);
                        checkBox.FontColor = app.fontColor;
                        checkBox.Layout.Row = n;
                        checkBox.Layout.Column = 2;
                        checkBox.Text = "";
                        checkBox.FontSize = 14;

                        app.componentsList(n) = {checkBox};

                    case "InputSegmentationsSelector"
                        % Create a numeric edit field for input
                        % segmentations selection.        
                        segmentationSelector = app.createNumericEditField(n, 2, [1, length(segmentations)], 1);

                        app.componentsList(n) = {segmentationSelector};

                    case "Number"
                        % Create a numeric edit field for a numeric input.
                        numericEditField = app.createNumericEditField(n, 2, input.limits, input.limits(1));

                        app.componentsList(n) = {numericEditField};

                end

                % Create help hyperlink in the 3rd column.
                helpHyperlink = uihyperlink(app.InputGridLayout, "HyperlinkClickedFcn",...
                    {@app.displayHelpBox, input.name, input.help});
                helpHyperlink.VisitedColor = app.linkColor;
                helpHyperlink.Text = "Help...";
                helpHyperlink.HorizontalAlignment = "center";
                helpHyperlink.Layout.Row = n;
                helpHyperlink.Layout.Column = 3;
                helpHyperlink.FontSize = 14;

                n = n + 1;
            end

            % Center the dialog box.
            app.ParametersUIFigure.Visible = "off";
            movegui(app.ParametersUIFigure, "center");
            app.ParametersUIFigure.Visible = "on";
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            nParam = length(app.componentsList);

            % Get all the parameters.
            parameters = cell(nParam, 1);
            for i = 1:nParam
                component = app.componentsList(i);
                component = component{:};

                % Check if all the parameters are filled.
                if isempty(component.Value)
                    text = "Please fill all the inputs";
                    uiconfirm(app.ParametersUIFigure, text, strcat("Input error "),...
                        "Options", "OK", "Icon", "error");
                    return;
                end
                parameters{i} = component.Value;
            end
            
            % Pass the input to main app and compute result.
            % Hide the input figure, it will be deleted after
            % the result is computed.
            app.NextButton.Enable = "off";
            app.ParametersUIFigure.Visible ="off";
            drawnow
            app.callingApp.computeResult(parameters{:});
            app.ParametersUIFigureCloseRequest();
        end

        % Close request function: ParametersUIFigure
        function ParametersUIFigureCloseRequest(app, event)
           % Remove the reference to the current input from the main app.
            app.callingApp.removeInputApp();
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.ParametersUIFigure = uifigure('Visible', 'off');
            app.ParametersUIFigure.Name = 'Parameters';
            app.ParametersUIFigure.Icon = fullfile(pathToMLAPP, 'logo', 'logo_nobg.png');
            app.ParametersUIFigure.CloseRequestFcn = createCallbackFcn(app, @ParametersUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.ParametersUIFigure);
            app.Panel = uipanel(app.GridLayout);
            app.InputGridLayout = uigridlayout(app.Panel);
            app.NextPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.NextPanel);
            app.NextButton = uibutton(app.GridLayout2, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Tag = 'p2_back';
            app.NextButton.Text = 'Next';
            % Show the figure after all components are created
            app.ParametersUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_input(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ParametersUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ParametersUIFigure)
        end
    end
end