classdef app_main < matlab.apps.AppBase
    %APP_MAIN Main app of TDSFT tool.
    %   AUTHOR: Lorenzo Drudi (E-mail: lorenzodrudi11@gmail.com)
    %   NAME:   TDSFT (version 1.0)
    %   
    %   It is the main app of the tool.
    %   From here the user can upload images.
    %   For each uploaded image it is possible to:
    %   - show it in an auxiliary app;
    %   - remove it from the list.
    %   
    %   Also, It is possible to set all the advanced features such as the
    %   presence of dense segmentation, the line type to use for the line
    %   of more than one pixel and the closing algorithm to use if the
    %   result segmentation is not already closed.
    %   
    %   Furthermore, it is possible to open the About app to see all the
    %   details about us and the tool.

    % Properties that correspond to app components
    properties (Access = public)
        TDSFTUIFigure              matlab.ui.Figure
        MainGridLayout             matlab.ui.container.GridLayout
        FinalPanel                 matlab.ui.container.Panel
        GridLayout                 matlab.ui.container.GridLayout
        LoadingLabel               matlab.ui.control.Label
        About                      matlab.ui.control.Hyperlink
        NextButton                 matlab.ui.control.Button
        FusionAlgorithmPanel       matlab.ui.container.Panel
        FusionAlgorithmGridLayout  matlab.ui.container.GridLayout
        FusionAlgorithmDropDown    matlab.ui.control.DropDown
        AlgorithmDropDownLabel     matlab.ui.control.Label
        SegPanel                   matlab.ui.container.Panel
        SegGridLayout              matlab.ui.container.GridLayout
        SegHandlerGridLayout       matlab.ui.container.GridLayout
        AdvancedFeatures           matlab.ui.control.Hyperlink
        UploadButton               matlab.ui.control.Button
        OpenButton                 matlab.ui.control.Button
        RemoveButton               matlab.ui.control.Button
        SegListBox                 matlab.ui.control.ListBox
    end

    
    properties (Access = private)
        % If `dense` is selected only external `line` can be used.
        % If an object is dense there is no `middle` or `internal` line.
        dense                 % If true, dense option selected

        closingLineAlgorithm  % Algorithm to use if resulting segmentation
                              % is not closed.

        onePixelLine          % The one pixel line to use if input 
                              % segmentation is of more than one pixel
       
        fileNames             % File names
        bwImages              % Black and white images
        segmentations         % Segmentations
        selectedSegmentations % Index of the selected segmentations
        inputApp              % Inputs app
        imageApps             % Array containing all the image apps opened
        aboutApp              % About app
        resultApps            % Array containing all the result apps opened
        advancedFeaturesApp   % Advanced feature app  
        controller      % The controller of the fusion process

        EMPTY_STRING          % Empty segmentation list string
    end
    
    methods (Access = private)
        
        % Returns true if the input button is enabled.
        function result = isEnabled(~, button)
            enableString = string(button.Enable);
            result = strcmpi(enableString, "on");
        end

        % Disable all the buttons that permit to modify the segmentation.
        function disableSegmentationsUpdate(app)
            app.NextButton.Enable = "off";
            app.RemoveButton.Enable = "off";
            app.UploadButton.Enable = "off";
            app.AdvancedFeatures.Enable = "off";
        end    

        % Load all the fusion algorithms from the 'api/fusionAlgorithms'
        % folder.
        function loadAlgorithms(app)
            algorithmsNames = dir(fullfile("api", "fusionAlgorithms",...
                strcat(Constants.ALGORITHM_NAMES_FILE_ROOT, "*", Constants.SRC_EXTENSION)));

            algorithmsNames = arrayfun(@(v) extractBetween(v.name,...
                Constants.ALGORITHM_NAMES_FILE_ROOT, Constants.SRC_EXTENSION), algorithmsNames);
            algorithmsNames = arrayfun(@(v) StringsUtils.fromCamelCaseToSpacedString(string(v)), algorithmsNames);
            app.FusionAlgorithmDropDown.Items = string(algorithmsNames);
        end
    
        % Load the segmentations names to the list box.
        % Add, for each segmentation, the index.
        function computeSegNameList(app)
            names = strings(1, length(app.fileNames));
            for i = 1:length(app.fileNames)
                segName = app.fileNames(i);
                idxString = strcat(string(i) , ": ");
                names(1, i) = strcat(idxString, segName);
            end
            app.SegListBox.Items = names;
            drawnow
        end
    end
    
    methods (Access = public)
        
        % Enable segmentations update.
        function enableSegmentationsUpdate(app)
            if length(app.fileNames) >= 2
                app.NextButton.Enable = "on";
            end
            app.UploadButton.Enable = "on";
            app.AdvancedFeatures.Enable = "on";
            if app.selectedSegmentations
                app.OpenButton.Enable = "on";
                app.RemoveButton.Enable ="on";
            end
        end
        
        % Enable about hyperlink.
        function enableAbout(app)
            app.About.Enable = "on";
            drawnow
        end

        % Enable advancedFeatures hyperlink.
        function enableAdvancedFeatures(app)
            app.AdvancedFeatures.Enable = "on";
            drawnow
        end

        % Set the value of the dense property.
        %   value: the value to set
        function setDense(app, value)
            if ~strcmp(app.onePixelLine, OnePixelLineTypes.EXTERNAL.string) && value
                uiconfirm(app.TDSFTUIFigure, "Dense option incompatible with any one pixel line type" + ...
                    "different from `external`. If an object is dense there is no middle or internal line." + ...
                    "To use dense option change first the one-pixel line type.", "ERROR", "Options", "OK", "Icon", "error");
            else
                app.dense = value;
                if value
                    disp("Dense feature selected");
                else
                    disp("Dense feature deselected");
                end
            end
        end

        % If the input segmentation has the line of more than one-pixel
        % the user can choose which line to use.
        % The options are: external, middle, or internal one-pixel line.
        %   line: the line to use
        %   
        % If the dense option is selected then only external line can be used.
        function setOnePixelLine(app, line)
            if ~strcmp(line, OnePixelLineTypes.EXTERNAL.string) && app.dense
                uiconfirm(app.TDSFTUIFigure, "The dense option is already selected. To use a one-pixel line type different from" + ...
                    "external first disable dense option." + ...
                    "If an object is dense there is no middle or internal line", "ERROR", "Options", "OK", "Icon", "error");
            else
                app.onePixelLine = line;
                s = strcat("One-pixel line type: ", line);
                disp(s);
            end
        end

        % Set the closing line algorithm to use if the resulting
        % segmentation is not closed.
        % (See api/closingLineAlgorithms folder for more details)
        function setClosingLineAlgorithm(app, algorithm)
            app.closingLineAlgorithm = algorithm;
            algorithmName = erase(algorithm, Constants.CLOSING_NAMES_FILE_ROOT);
            algorithmName = StringsUtils.fromCamelCaseToSpacedString(algorithmName);
            s = strcat("Closing line algorithm: ", algorithmName);
            disp(s);
        end

        % Remove the specified app from the input app tracked by the main
        % one
        function removeInputApp(app) 
            app.inputApp = [];
            app.disableWaiting();
        end

        % Disable the waiting status
        function disableWaiting(app)
            app.enableSegmentationsUpdate();
            app.NextButton.Enable = "on";
            app.LoadingLabel.Visible = "off";
            drawnow
        end

        function computeResult(app, varargin)
            
            % Get the algorithm to use fro the fusion process
            algorithm = app.FusionAlgorithmDropDown.Value;
            algorithm = StringsUtils.fromSpacedToFusionAlgorithmFullName(algorithm);

            % Check if the selected algorithm requires more input params
            if nargin > 1
                res = app.controller.executeFusion(app.segmentations, algorithm, app.closingLineAlgorithm, varargin{:});
            else
                res = app.controller.executeFusion(app.segmentations, algorithm, app.closingLineAlgorithm);
            end
            resultApp = app_result(app, res, app.segmentations);
            app.resultApps = [app.resultApps, resultApp];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Center the gui.
            app.TDSFTUIFigure.Visible = "off";
            movegui(app.TDSFTUIFigure, "center");
            app.TDSFTUIFigure.Visible = "on";
            
            % DEVELOP: remove the comment to run it from Matlab.
            % Run startup script to load all the directories to the Matlab path.
            % runStartup();
            
            % Initialize variables.
            app.dense = false;
            app.onePixelLine = OnePixelLineTypes.EXTERNAL.string;

            % Default closing line algorithm.
            app.closingLineAlgorithm = strcat(Constants.CLOSING_NAMES_FILE_ROOT, "ShapePreserving");

            app.fileNames = {};
            app.bwImages = {};
            app.segmentations = {};
            app.imageApps = [];
            app.resultApps = [];
            app.aboutApp = [];
            app.advancedFeaturesApp = [];
            app.inputApp = [];
            app.selectedSegmentations = [];
            app.EMPTY_STRING = "No segmentation loaded";
            app.controller = ControllerImpl;

            % Load segmentation fusion algorithms.
            app.loadAlgorithms();
            
            % Disable segmentations handlers buttons.
            app.RemoveButton.Enable = "off";
            app.OpenButton.Enable = "off";
            app.NextButton.Enable = "off";
            app.SegListBox.Enable = "off";
            app.LoadingLabel.Visible = "off";

            drawnow
        end

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            app.UploadButton.Enable = "off";
            drawnow

            % Upload segmentations.
            % Change here if more image formats are needed.
            [inputNames, inputPath] = uigetfile( ...
                "*.jpg;*.png;*.jpeg;*.bmp;*.tif;*.tiff;*.gif", ...
                "Select one or more segmentations", ...
                "MultiSelect","on");

            % Reopen app's window.
            % Solve a known issue of the function `uigetfile` (see: https://it.mathworks.com/matlabcentral/answers/296305-appdesigner-window-ends-up-in-background-after-uigetfile).
            figure(app.TDSFTUIFigure);
            app.LoadingLabel.Visible ="on";
            drawnow

            % Check if there are any inputs.
            if isequal(inputNames, 0) || isequal(inputPath, 0) 
                fprintf("Selected Cancel\n");
            else

                if ~iscell(inputNames)
                    inputNames = {inputNames};
                end
                for name = inputNames
                    try 
                        % Check if the parameters are right.
                        if app.dense && ~isequal(app.onePixelLine, OnePixelTypes.EXTERNAL.string)
                            ME = MException("TDSFT:processImage", "If dense option is selected only one pixel external line can be used.");
                            throw(ME);
                        end

                        % Process image.
                        fullname = fullfile(inputPath, string(name));
                        img = imread(fullname);                       
                        [bw, seg, wrn] = app.controller.processImage(img, app.dense, app.onePixelLine);

                        % check if the new inserted image has size equals
                        % to the first one.
                        if ~ isempty(app.fileNames)
                            [height, width] = size(app.segmentations{1});
                            [newHeight, newWidth] = size(bw);
                            if ~( isequal(height, newHeight) && isequal(width, newWidth) )
                                message = "The input image - " + name + " - has size different from the first inserted. The size must be " ...
                                    + height + " x " + width;
                                uiconfirm(app.TDSFTUIFigure, message, "WARNING", "Options", "OK", "Icon", "warning");
                                continue;
                            end
                        end

                        if wrn
                            message = "The input image - " + name + " - has more than one channel but is not an rgb image. Used only the first channel";
                            uiconfirm(app.TDSFTUIFigure, message, "WARNING", "Options", "OK", "Icon", "warning");
                        end

                        % Store inputs.
                        fprintf("Image selected: %s\n", string(name));

                        %Remove file extension.
                        minimalName = StringsUtils.removeFileExtension(name);
                        app.fileNames = [app.fileNames, minimalName];
                        app.bwImages = [app.bwImages, bw];
                        app.segmentations = [app.segmentations, seg];
                    % Catch error and discard image.
                    catch ME
                        switch ME.identifier
                            case "TDSFT:processImage"
                                fprintf("Image discarded: %s\n", string(name));
                                uialert(app.TDSFTUIFigure, ME.message, "Image Discarded");
                            otherwise
                                rethrow(ME);
                        end
                    end
                end
                
                % Update ListBox.
                app.computeSegNameList();
                
                if ~app.isEnabled(app.SegListBox)
                    app.SegListBox.Enable = "on";
                end

                if length(app.fileNames) >= 2
                    app.NextButton.Enable = "on";
                end
            end
            app.LoadingLabel.Visible = "off";
            app.UploadButton.Enable = "on";
            drawnow
        end

        % Clicked callback: SegListBox
        function SegListBoxClicked(app, event)
            % Check if there is at least one segmentation loaded.
            if isempty(app.segmentations)
                return
            end
            
            % Get selected segmentation's index.
            item = event.InteractionInformation.Item;
            app.selectedSegmentations = item;

            % If there is an opened input up it's not possible to remove a
            % segmentation.
            if ~app.isEnabled(app.RemoveButton) && isempty(app.inputApp)
                app.RemoveButton.Enable ="on";
            end
            if ~app.isEnabled(app.OpenButton)
                app.OpenButton.Enable ="on";
            end
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            % Check if there is a selected segmentation.
            if isempty(app.selectedSegmentations)
                return
            end
    
            % Get all the data related to the selected image.
            inName = app.fileNames{app.selectedSegmentations};
            inBwImg = app.bwImages{app.selectedSegmentations};
            inSeg = app.segmentations{app.selectedSegmentations};

            % Open the image's app.
            fprintf("Image opened: %s\n", string(inName));          
            imageApp = app_image(inName, inBwImg, inSeg);

            % Get a reference to the app to be aple to delete it.
            app.imageApps = [app.imageApps, imageApp];
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            % Check if there is a segmentation selected.
            if app.selectedSegmentations
                % Remove the segmentation.
                inName = app.fileNames{app.selectedSegmentations};
                app.fileNames(app.selectedSegmentations) = [];
                app.bwImages(app.selectedSegmentations) = [];
                app.segmentations(app.selectedSegmentations) = [];

                fprintf("Image removed: %s\n", string(inName));          
                % Add empty string message it there is no segmentation
                % left.
                if isempty(app.fileNames)
                    app.SegListBox.Items = [app.EMPTY_STRING];
                    app.SegListBox.Enable = "off";
                    drawnow
                else
                    app.computeSegNameList();
                end

                if app.isEnabled(app.NextButton) && length(app.fileNames) < 2
                    app.NextButton.Enable = "off";
                end

                % Remove selected segmentations and disable buttons.
                app.selectedSegmentations = [];
                app.OpenButton.Enable = "off";
                app.RemoveButton.Enable = "off";
                drawnow
            end
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            % Disable everything until the computing is not finished.
            app.LoadingLabel.Visible = "on";
            app.disableSegmentationsUpdate();
            drawnow

            algorithm = app.FusionAlgorithmDropDown.Value;

            % Get algorithm fullname.
            algorithm = StringsUtils.fromSpacedToFusionAlgorithmFullName(algorithm);
            
            % Get input full file name.
            inputFile = strcat(algorithm, Constants.INPUT_FILE_EXTENSION);
            inputFile = fullfile("api", "fusionAlgorithms", "inputs", inputFile);

            try
                % Check if the algorithm need user inputs. 
                % (then the input app will call computeResult function).
                if exist(inputFile, "file") && ~(strcmpi(strcat(Constants.ALGORITHM_NAMES_FILE_ROOT,"Middle"), algorithm)...
                        && ~isequal(mod(length(app.segmentations), 2), 0))                        
                    app.inputApp = app_input(app, algorithm, app.segmentations); 
                else
                    app.computeResult();
                end
            catch ME
                switch ME.identifier
                    case "TDSFT:fusionProcess"
                        disp("Segmentation fusion failed");
                        uialert(app.TDSFTUIFigure, ME.message, "Segmentation fusion failed");
                    otherwise
                        rethrow(ME);
                end
            end
        end

        % Callback function: About
        function AboutClicked(app, event)
            app.About.Enable = "off";
            drawnow

            app.aboutApp = app_about(app);
        end

        % Callback function: AdvancedFeatures
        function AdvancedFeaturesClicked(app, event)
            app.AdvancedFeatures.Enable = "off";
            drawnow

            app.advancedFeaturesApp = app_advancedFeatures(app, app.dense, app.onePixelLine, app.closingLineAlgorithm);
        end

        % Close request function: TDSFTUIFigure
        function TDSFTUIFigureCloseRequest(app, event)
            delete(app.inputApp);
            delete(app.aboutApp);
            delete(app.advancedFeaturesApp);

            for imageApp = app.imageApps
                delete(imageApp);
            end

            for resultApp = app.resultApps       
                delete(resultApp);
            end

            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.TDSFTUIFigure = uifigure('Visible', 'off');
            app.TDSFTUIFigure.Name = 'TDSFT';
            app.TDSFTUIFigure.Icon = fullfile(pathToMLAPP, 'logo', 'logo_nobg.png');
            app.TDSFTUIFigure.CloseRequestFcn = createCallbackFcn(app, @TDSFTUIFigureCloseRequest, true);
            app.MainGridLayout = uigridlayout(app.TDSFTUIFigure);
            app.SegPanel = uipanel(app.MainGridLayout);
            app.SegPanel.Title = 'Segmentations';
            app.SegGridLayout = uigridlayout(app.SegPanel);
            app.SegListBox = uilistbox(app.SegGridLayout);
            app.SegListBox.Items = {'No segmentation loaded'};
            app.SegListBox.ClickedFcn = createCallbackFcn(app, @SegListBoxClicked, true);
            app.SegListBox.Value = {};
            app.SegHandlerGridLayout = uigridlayout(app.SegGridLayout);
            app.RemoveButton = uibutton(app.SegHandlerGridLayout, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = 'Remove';
            app.OpenButton = uibutton(app.SegHandlerGridLayout, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.UploadButton = uibutton(app.SegHandlerGridLayout, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = 'Upload';
            app.AdvancedFeatures = uihyperlink(app.SegHandlerGridLayout);
            app.AdvancedFeatures.HyperlinkClickedFcn = createCallbackFcn(app, @AdvancedFeaturesClicked, true);
            app.AdvancedFeatures.Text = ['Advanced' newline 'Features'];
            app.FusionAlgorithmPanel = uipanel(app.MainGridLayout);
            app.FusionAlgorithmGridLayout = uigridlayout(app.FusionAlgorithmPanel);
            app.AlgorithmDropDownLabel = uilabel(app.FusionAlgorithmGridLayout);
            app.AlgorithmDropDownLabel.Text = 'Algorithm';
            app.FusionAlgorithmDropDown = uidropdown(app.FusionAlgorithmGridLayout);
            app.FusionAlgorithmDropDown.Items = {};
            app.FusionAlgorithmDropDown.Value = {};
            app.FinalPanel = uipanel(app.MainGridLayout);
            app.GridLayout = uigridlayout(app.FinalPanel);
            app.NextButton = uibutton(app.GridLayout, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.About = uihyperlink(app.GridLayout);
            app.About.HyperlinkClickedFcn = createCallbackFcn(app, @AboutClicked, true);
            app.About.Text = 'About';
            app.LoadingLabel = uilabel(app.GridLayout);
            app.LoadingLabel.Text = 'Loading...';
            % Show the figure after all components are created
            app.TDSFTUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TDSFTUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TDSFTUIFigure)
        end
    end
end