classdef app_result < matlab.apps.AppBase
    %APP_RESULT Result segmentation window of TDSFT tool.
    %   AUTHOR: Lorenzo Drudi (E-mail: lorenzodrudi11@gmail.com)
    %   NAME:   TDSFT (version 1.0)
    %   
    %   It permits to show the result segmentation.
    %   It is possible to:
    %   - apply a dilation;
    %   - overlap the original segmentations (light-gray color);
    %   - save the result.
    %   
    %   Parameters:
    %     - mainApp:
    %         the calling app;
    %     - result (Matrix [height, width]):
    %         the resulting segmentation;
    %     - segmentations (Cell array: [1, nSeg], Cells: matrix [height,
    %   width]):
    %         the original segmentations.

    % Properties that correspond to app components
    properties (Access = public)
        ResultUIFigure         matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        SavePanel              matlab.ui.container.Panel
        SliderGridLayout       matlab.ui.container.GridLayout
        ThicknessSlider        matlab.ui.control.Slider
        ThicknessSliderLabel   matlab.ui.control.Label
        SliderPanel            matlab.ui.container.Panel
        SaveGridLayout         matlab.ui.container.GridLayout
        ShowOriginalsCheckBox  matlab.ui.control.CheckBox
        SaveButton             matlab.ui.control.Button
        Panel                  matlab.ui.container.Panel
        ImageGridLayout        matlab.ui.container.GridLayout
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private) 
        mainApp                         % Reference to the calling app (main)
        result                          % Image to display
        segmentations                   % Orignal segmentations
        resultWithOriginalSegmentations % Result with original segmentations 
    end
    
    methods (Access = private)
        
        function showResult(app)
            imshow(app.result, [], "Parent", app.UIAxes);
            drawnow
        end

        function showResultWithOriginalSegmentations(app)
            imshow(app.resultWithOriginalSegmentations, [], "Parent", app.UIAxes);
            drawnow
        end

        function overlap = overlapResultWithOriginalSegmentations(app)
            GRAY_COLOR = 128;
            WHITE_COLOR = 255;
            WHITE_COLOR_BINARY = 1;

            % Create the image with the original segmentations overlapped
            % in gray and the fusion result in white.
            [height, width] = size(app.result);
            overlap = overlapSegmentations(app.segmentations);
            overlap = imbinarize(overlap);
            overlap = uint8(GRAY_COLOR * overlap);
            overlap = overlap + uint8(app.result);

            for i = 1:height
                for j = 1:width
                    if overlap(i, j) > GRAY_COLOR || overlap(i, j)...
                        == WHITE_COLOR_BINARY
                        overlap(i, j) = WHITE_COLOR;
                    end
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, result, segmentations)
            app.ResultUIFigure.Visible ="off";
            movegui(app.ResultUIFigure, "center");
            app.ResultUIFigure.Visible ="on";

            % Enable only zoom interaction for the image.
            app.UIAxes.Interactions = zoomInteraction;

            app.mainApp = mainApp;
            app.result = uint8(result);
            app.segmentations = segmentations;
            app.resultWithOriginalSegmentations = app.overlapResultWithOriginalSegmentations();

            imshow(app.result, [], "Parent", app.UIAxes);
            drawnow

            app.mainApp.disableWaiting();
        end

        % Value changed function: ThicknessSlider
        function ThicknessSliderValueChanged(app, event)
            % Get slider value.
            value = app.ThicknessSlider.Value;

            % Re-adapt the value because level 1 of thickness
            % is the original one (so no dilation).
            value = value - 1;

            % Round to nearest integer.
            value = round(value);
            
            dilImg = imdilate(app.result, strel("diamond", value));
            imshow(dilImg, [], "Parent", app.UIAxes);
            drawnow
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % 'result.png' is the default name.
            [file, path] = uiputfile( ...
                "*.jpg;*.png;*.jpeg;*.bmp;*.tif;*.tiff;*.gif", ...
                "Save to File", ...
                "result.png");
            if file
                % Convert the result to int8 
                % setting the non-zero pixel to 255
                % (needed to be able to open the image from
                % any common image viewer)
                res = uint8(app.result);
                for i = 1:size(res,1)
                    for j = 1:size(res,2)
                        if res(i,j) > 0
                            res(i,j) = 255;
                        end
                    end
                end
                imwrite(res, fullfile(path, file));
            end
        end

        % Value changed function: ShowOriginalsCheckBox
        function ShowOriginalsCheckBoxValueChanged(app, event)
            value = app.ShowOriginalsCheckBox.Value;
            
            if value
                app.ThicknessSlider.Enable = "off";
                app.showResultWithOriginalSegmentations();
            else
                app.ThicknessSlider.Enable = "on";
                app.showResult();
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.ResultUIFigure = uifigure('Visible', 'off');
            app.ResultUIFigure.Name = 'Result';
            app.ResultUIFigure.Icon = fullfile(pathToMLAPP, 'logo', 'icon_nobg.png');
            app.GridLayout = uigridlayout(app.ResultUIFigure);
            app.Panel = uipanel(app.GridLayout);
            app.ImageGridLayout = uigridlayout(app.Panel);
            app.UIAxes = uiaxes(app.ImageGridLayout);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.SliderPanel = uipanel(app.GridLayout);
            app.SaveGridLayout = uigridlayout(app.SliderPanel);
            app.SaveButton = uibutton(app.SaveGridLayout, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ShowOriginalsCheckBox = uicheckbox(app.SaveGridLayout);
            app.ShowOriginalsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowOriginalsCheckBoxValueChanged, true);
            app.ShowOriginalsCheckBox.Text = 'Show Originals';
            app.SavePanel = uipanel(app.GridLayout);
            app.SliderGridLayout = uigridlayout(app.SavePanel);
            app.ThicknessSliderLabel = uilabel(app.SliderGridLayout);
            app.ThicknessSliderLabel.Text = 'Thickness';
            app.ThicknessSlider = uislider(app.SliderGridLayout);
            app.ThicknessSlider.Limits = [1 17];
            app.ThicknessSlider.MajorTicks = [1 3 5 7 9 11 13 15 17];
            app.ThicknessSlider.ValueChangedFcn = createCallbackFcn(app, @ThicknessSliderValueChanged, true);
            app.ThicknessSlider.MinorTicks = [0 2 4 6 8 10 12 14 16];
            app.ThicknessSlider.Value = 1;
            % Show the figure after all components are created
            app.ResultUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_result(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ResultUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ResultUIFigure)
        end
    end
end