classdef imageTDSFT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        ImageGridLayout       matlab.ui.container.GridLayout
        SelectionPanel        matlab.ui.container.Panel
        SelectionGridLayout   matlab.ui.container.GridLayout
        SliderPanel           matlab.ui.container.Panel
        SliderGridLayout      matlab.ui.container.GridLayout
        ThicknessSlider       matlab.ui.control.Slider
        ThicknessSliderLabel  matlab.ui.control.Label
        ShowGroup             matlab.ui.container.ButtonGroup
        Segmentation          matlab.ui.control.RadioButton
        Blackandwhite         matlab.ui.control.RadioButton
        Original              matlab.ui.control.RadioButton
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        callingApp % Main app
        name % Image name
        img % Original image
        bwImg % Black and white image
        seg % Segmentation of the object in the image
    end
    
    methods (Access = private)
        
        function hideSlider(app)
            app.SliderPanel.Visible = "off";
            app.ShowGroup.Layout.Column = [2 3];
        end
        
        function showSlider(app)
            app.SliderPanel.Visible = "on";
            app.ShowGroup.Layout.Column = [1 2];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, inName, inImg, inBwImg, inSeg)
            app.UIAxes.Title = app.name;
            
            % Hide segmentation's thickness slider
            app.hideSlider();

            % Disable UIAxes interactivity
            disableDefaultInteractivity(app.UIAxes);

            % Center the dialog box
            app.UIFigure.Visible = "off";
            movegui(app.UIFigure, "center");
            app.UIFigure.Visible = "on";

            % Store input params
            app.callingApp = caller;
            app.name = inName;
            app.img = inImg;
            app.bwImg = inBwImg;
            app.seg = inSeg;

            % Show, as default, the original image
            imshow(app.img, [], 'Parent', app.UIAxes);
        end

        % Callback function
        function CloseButtonPushed(app, event)
            delete(app);
        end

        % Selection changed function: ShowGroup
        function ShowGroupSelectionChanged(app, event)
            selectedButton = app.ShowGroup.SelectedObject;
            switch selectedButton
                case app.Original
                    app.hideSlider();
                    imshow(app.img, [], 'Parent', app.UIAxes);
                    return;
                case app.Segmentation
                    imshow(app.seg, [], 'Parent', app.UIAxes);
                    app.showSlider();
                    return
                case app.Blackandwhite
                    app.hideSlider();
                    imshow(app.bwImg, [], 'Parent', app.UIAxes);
                    return
            end
        end

        % Value changed function: ThicknessSlider
        function ThicknessSliderValueChanged(app, event)
            % Apply dilation on the starting segmentation
            value = app.ThicknessSlider.Value;
            % Round to nearest integer
            value = round(value);
            dilImg = imdilate(app.seg, strel('diamond', value));
            imshow(dilImg, [], 'Parent', app.UIAxes);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImageGridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.ImageGridLayout);
            title(app.UIAxes, 'Prova')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.SelectionPanel = uipanel(app.ImageGridLayout);
            app.SelectionGridLayout = uigridlayout(app.SelectionPanel);
            app.ShowGroup = uibuttongroup(app.SelectionGridLayout);
            app.ShowGroup.SelectionChangedFcn = createCallbackFcn(app, @ShowGroupSelectionChanged, true);
            app.ShowGroup.Title = 'Show:';
            app.Original = uiradiobutton(app.ShowGroup);
            app.Original.Text = 'Original Image';
            app.Original.Value = true;
            app.Blackandwhite = uiradiobutton(app.ShowGroup);
            app.Blackandwhite.Text = 'Black-and-white Image';
            app.Segmentation = uiradiobutton(app.ShowGroup);
            app.Segmentation.Text = 'Segmentation';
            app.SliderPanel = uipanel(app.SelectionGridLayout);
            app.SliderGridLayout = uigridlayout(app.SliderPanel);
            app.ThicknessSliderLabel = uilabel(app.SliderGridLayout);
            app.ThicknessSliderLabel.Text = 'Thickness';
            app.ThicknessSlider = uislider(app.SliderGridLayout);
            app.ThicknessSlider.Limits = [1 19];
            app.ThicknessSlider.MajorTicks = [1 3 5 7 9 11 13 15 17 19];
            app.ThicknessSlider.ValueChangedFcn = createCallbackFcn(app, @ThicknessSliderValueChanged, true);
            app.ThicknessSlider.MinorTicks = [2 4 6 8 10 12 14 16 18];
            app.ThicknessSlider.Value = 7.43548387096774;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = imageTDSFT(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end