classdef inputTDSFT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        InputGridLayout  matlab.ui.container.GridLayout
        NextButton       matlab.ui.control.Button
        CloseButton      matlab.ui.control.Button
    end

    
    properties (Access = private)
        callingApp % Main app
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            % Center the dialog box
            app.UIFigure.Visible = "off";
            movegui(app.UIFigure, 'center');
            app.UIFigure.Visible = "on";
            
            % Store input param
            app.callingApp = caller;
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.UIFigureCloseRequest();
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            % TODO:
            % Call a public function of the main app to execute the
            % algorithm and compute the fusion
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.callingApp.EnableAllButtons();

            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.InputGridLayout = uigridlayout(app.UIFigure);
            app.CloseButton = uibutton(app.InputGridLayout, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Tag = 'p2_back';
            app.CloseButton.Text = 'Close';
            app.NextButton = uibutton(app.InputGridLayout, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Tag = 'p2_back';
            app.NextButton.Text = 'Next';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = inputTDSFT(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end