classdef mainSFT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        InputPanel              matlab.ui.container.Panel
        GridLayout7             matlab.ui.container.GridLayout
        InputNextButton         matlab.ui.control.Button
        InputBackButton         matlab.ui.control.Button
        ImagePanel              matlab.ui.container.Panel
        GridLayout8             matlab.ui.container.GridLayout
        ImageBackButton         matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
        MainGridLayout          matlab.ui.container.GridLayout
        IntroPanel              matlab.ui.container.Panel
        CentralGridLayout       matlab.ui.container.GridLayout
        Panel_2                 matlab.ui.container.Panel
        GridLayout6             matlab.ui.container.GridLayout
        AlgorithmDropDown       matlab.ui.control.DropDown
        AlgorithmDropDownLabel  matlab.ui.control.Label
        Panel                   matlab.ui.container.Panel
        GridLayout3             matlab.ui.container.GridLayout
        Panel_4                 matlab.ui.container.Panel
        GridLayout5             matlab.ui.container.GridLayout
        RemoveButton            matlab.ui.control.Button
        OpenButton              matlab.ui.control.Button
        Panel_3                 matlab.ui.container.Panel
        GridLayout4             matlab.ui.container.GridLayout
        UploadButton            matlab.ui.control.Button
        ListBox                 matlab.ui.control.ListBox
        IntroNextButton         matlab.ui.control.Button
        Docs                    matlab.ui.control.Hyperlink
        Author                  matlab.ui.control.Label
        Title                   matlab.ui.control.Label
    end

    
    properties (Access = private)
        filenames % Segmentations' files
        filepaths % Segmentations' path
        selectedSegmentations % Index of the selected segmentations
    end
    
    methods (Access = private)
        
        function result = isEnabled(~, button)
            enableString = string(button.Enable);
            result = strcmpi(enableString, "on");
        end

        function addInputPanel(app)
            app.InputPanel = uipanel(app.MainGridLayout);
            app.InputPanel.BorderType = 'none';
            %app.InputPanel.BackgroundColor = [0.651 0.651 0.651];
            app.InputPanel.Layout.Row = [2 7];
            app.InputPanel.Layout.Column = [1 3];

            % Create InputBackButton
            app.InputBackButton = uibutton(app.GridLayout7, 'push');
            app.InputBackButton.ButtonPushedFcn = createCallbackFcn(app, @InputBackButtonPushed, true);
            app.InputBackButton.Tag = 'p2_back';
            app.InputBackButton.FontWeight = 'bold';
            app.InputBackButton.Layout.Row = 6;
            app.InputBackButton.Layout.Column = 1;
            app.InputBackButton.Text = 'Back';

            % Create InputNextButton
            app.InputNextButton = uibutton(app.GridLayout7, 'push');
            app.InputNextButton.Tag = 'p2_back';
            app.InputNextButton.FontWeight = 'bold';
            app.InputNextButton.Layout.Row = 6;
            app.InputNextButton.Layout.Column = 3;
            app.InputNextButton.Text = 'Next';
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.addInputPanel();
            
            % Hide not needed panels
            %app.InputPanel.Visible = "off";
            app.IntroPanel.Visible = "off";
            app.ImagePanel.Visible = "off";

            % Disable segmentations handlers buttons
            app.RemoveButton.Enable = "off";
            app.OpenButton.Enable = "off";
            app.IntroNextButton.Enable = "off";
            
            % center the gui
            app.UIFigure.Visible = "off";
            movegui(app.UIFigure, 'center');
            app.UIFigure.Visible = "on";

            % Initialize variables
            app.filenames = {};
            app.filepaths = {};
            app.selectedSegmentations = [];
        end

        % Button pushed function: IntroNextButton
        function IntroNextButtonPushed(app, event)
            app.IntroPanel = uipanel(app.MainGridLayout);
            app.IntroPanel.Layout = app.IntroPanel.Layout;
            app.InputPanel.Position = app.IntroPanel.Position;

            app.IntroPanel.Visible = "off";
            app.InputPanel.Visible = "on";
        end

        % Button pushed function: InputBackButton
        function InputBackButtonPushed(app, event)
            app.IntroPanel = uipanel(app.MainGridLayout);
            app.IntroPanel.Layout= app.InputPanel.Layout;
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            %Check if a segmentation is selected
            if isempty(app.selectedSegmentations)
                return
            end

            %View the selected segmentation 
    
            %Get the file
            filename = app.filenames{app.selectedSegmentations};
            filepath = app.filepaths{app.selectedSegmentations};

            %Change panel
            app.IntroPanel.Visible = "off";
            app.ImagePanel.Position = app.IntroPanel.Position;
            app.ImagePanel.Visible = "on";

            %Load the image
            fullname = [filepath, filename];
            imageFile = imread(fullname);
            imagesc(app.UIAxes, imageFile);
        end

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            position = app.UIFigure.Position;

            % Upload segmentations
            [inputNames, inputPath] = uigetfile( ...
                {'*.png';'*.jpg'}, ...
                'Select the segmentations you want to fuse', ...
                'MultiSelect','on');

            % Reopen app's window
            % Solve a known issue of the function `uigetfile` (see: https://it.mathworks.com/matlabcentral/answers/296305-appdesigner-window-ends-up-in-background-after-uigetfile)
            figure(app.UIFigure);
            app.UIFigure.Position = position;
            
            % Update ListBox
            if isequal(inputNames, 0)
                fprintf('User selected Cancel\n');
            else
                inputPath = cellstr(repmat(inputPath, length(inputNames), 1)).';
                
                % Store inputs
                app.filenames = [app.filenames, inputNames];
                app.filepaths = [app.filepaths, inputPath];
                
                fprintf('User selected: %s\n', string(inputNames));
                app.ListBox.Items = cellstr(app.filenames);

                if length(app.filenames) >= 2
                    app.IntroNextButton.Enable = "on";
                end
            end
        end

        % Clicked callback: ListBox
        function ListBoxClicked(app, event)
            % Get selected segmentation's index
            item = event.InteractionInformation.Item;
            app.selectedSegmentations = item;

            % Enable buttons
            if ~app.isEnabled(app.RemoveButton)
                app.RemoveButton.Enable ="on";
            end
            if ~app.isEnabled(app.OpenButton)
                app.OpenButton.Enable ="on";
            end
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            % Check if there is a segmentation selected
            if ~isempty(app.selectedSegmentations)
                % Remove the segmentation
                app.filenames(:,app.selectedSegmentations) = [];
                app.filepaths(:,app.selectedSegmentations) = [];

                % Update the ListBox
                app.ListBox.Items = cellstr(app.filenames);
                
                if app.isEnabled(app.IntroNextButton) && length(app.filenames) < 2
                    app.IntroNextButton.Enable = "off";
                end

                % Remove selected segmentations and disable buttons
                app.selectedSegmentations = [];
                app.OpenButton.Enable = "off";
                app.RemoveButton.Enable = "off";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MainGridLayout = uigridlayout(app.UIFigure);
            app.Title = uilabel(app.MainGridLayout);
            app.Title.Text = '2DSFT - "2 dimensional segmentation fusion tool"';
            app.Author = uilabel(app.MainGridLayout);
            app.Author.Tag = 'author';
            app.Author.Text = '@LorenzoDrudi';
            app.Docs = uihyperlink(app.MainGridLayout);
            app.Docs.Tag = 'docsLink';
            app.Docs.Text = 'Docs';
            app.IntroPanel = uipanel(app.MainGridLayout);
            app.CentralGridLayout = uigridlayout(app.IntroPanel);
            app.IntroNextButton = uibutton(app.CentralGridLayout, 'push');
            app.IntroNextButton.ButtonPushedFcn = createCallbackFcn(app, @IntroNextButtonPushed, true);
            app.IntroNextButton.Tag = 'p1_next';
            app.IntroNextButton.Text = 'Next';
            app.Panel = uipanel(app.CentralGridLayout);
            app.GridLayout3 = uigridlayout(app.Panel);
            app.ListBox = uilistbox(app.GridLayout3);
            app.ListBox.Items = {};
            app.ListBox.Tag = 'p1_segmentationsList';
            app.ListBox.ClickedFcn = createCallbackFcn(app, @ListBoxClicked, true);
            app.ListBox.Value = {};
            app.Panel_3 = uipanel(app.GridLayout3);
            app.GridLayout4 = uigridlayout(app.Panel_3);
            app.UploadButton = uibutton(app.GridLayout4, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Tag = 'p1_uploadButton';
            app.UploadButton.Text = 'Upload';
            app.Panel_4 = uipanel(app.GridLayout3);
            app.GridLayout5 = uigridlayout(app.Panel_4);
            app.OpenButton = uibutton(app.GridLayout5, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Tag = 'p1_openButton';
            app.OpenButton.Text = 'Open';
            app.RemoveButton = uibutton(app.GridLayout5, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Tag = 'p1_removeButton';
            app.RemoveButton.Text = 'Remove';
            app.Panel_2 = uipanel(app.CentralGridLayout);
            app.GridLayout6 = uigridlayout(app.Panel_2);
            app.AlgorithmDropDownLabel = uilabel(app.GridLayout6);
            app.AlgorithmDropDownLabel.Text = 'Algorithm';
            app.AlgorithmDropDown = uidropdown(app.GridLayout6);
            app.AlgorithmDropDown.Items = {'STAPLE', 'Local MAP STAPLE'};
            app.AlgorithmDropDown.Tag = 'p1_algorithmsList';
            app.AlgorithmDropDown.Value = 'STAPLE';
            app.ImagePanel = uipanel(app.UIFigure);
            app.GridLayout8 = uigridlayout(app.ImagePanel);
            app.UIAxes = uiaxes(app.GridLayout8);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.ImageBackButton = uibutton(app.GridLayout8, 'push');
            app.ImageBackButton.Tag = 'p3_back';
            app.ImageBackButton.Text = 'Back';
            app.InputPanel = uipanel(app.UIFigure);
            app.GridLayout7 = uigridlayout(app.InputPanel);
            app.InputBackButton = uibutton(app.GridLayout7, 'push');
            app.InputBackButton.ButtonPushedFcn = createCallbackFcn(app, @InputBackButtonPushed, true);
            app.InputBackButton.Tag = 'p2_back';
            app.InputBackButton.Text = 'Back';
            app.InputNextButton = uibutton(app.GridLayout7, 'push');
            app.InputNextButton.Tag = 'p2_back';
            app.InputNextButton.Text = 'Next';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainSFT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end